classdef gui_test < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        Controller2Label       matlab.ui.control.Label
        Controller1Label       matlab.ui.control.Label
        COMEditField_2         matlab.ui.control.NumericEditField
        COMEditFieldLabel_2    matlab.ui.control.Label
        SelectCOMportButton_2  matlab.ui.control.Button
        MagnetButton           matlab.ui.control.StateButton
        ResetButton            matlab.ui.control.Button
        COMEditField           matlab.ui.control.NumericEditField
        COMEditFieldLabel      matlab.ui.control.Label
        SelectCOMportButton    matlab.ui.control.Button
        DetectedColorLabel     matlab.ui.control.Label
        EditField_2            matlab.ui.control.EditField
        DetectButton           matlab.ui.control.Button
        CameraViewLabel        matlab.ui.control.Label
        StartCameraButton      matlab.ui.control.Button
        ZEditField             matlab.ui.control.NumericEditField
        ZEditFieldLabel        matlab.ui.control.Label
        YEditField             matlab.ui.control.NumericEditField
        YEditFieldLabel        matlab.ui.control.Label
        XEditField             matlab.ui.control.NumericEditField
        XEditFieldLabel        matlab.ui.control.Label
        WorkPositionLabel      matlab.ui.control.Label
        InverseButton_2        matlab.ui.control.Button
        JointPositionLabel     matlab.ui.control.Label
        ZaxisEditField         matlab.ui.control.NumericEditField
        ZaxisEditFieldLabel    matlab.ui.control.Label
        Joint2EditField        matlab.ui.control.NumericEditField
        Joint2EditFieldLabel   matlab.ui.control.Label
        Joint1EditField        matlab.ui.control.NumericEditField
        Joint1EditFieldLabel   matlab.ui.control.Label
        ForwardButton          matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    properties (Access = private)
        cam
    end
    
    properties (Access = public)
        a
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed2(comp, event)
            Theta1=comp.Joint1EditField.Value;
            Theta2=comp.Joint2EditField.Value;
            Theta3=comp.ZaxisEditField.Value;
            assignin('base','inTheta1',Theta1);
            assignin('base','inTheta2',Theta2);
            assignin('base','inTheta3',Theta3);
            out=fk_3dof(Theta1,Theta2,Theta3);
            comp.XEditField.Value=out(1,1);
            comp.YEditField.Value=out(1,2);
            comp.ZEditField.Value=out(1,3);
            evalin("base",'FK_control');
        end

        % Button pushed function: InverseButton_2
        function InverseButton_2Pushed(comp, event)
            X=comp.XEditField.Value;
            Y=comp.YEditField.Value;
            Z=comp.ZEditField.Value;
            out=ik_2dof(X,Y,Z);
            comp.Joint1EditField.Value=out(1,1);
            comp.Joint2EditField.Value=out(1,2);
            comp.ZaxisEditField.Value=out(1,3);
            assignin('base','inTheta1',out(1,1));
            assignin('base','inTheta2',out(1,2));
            assignin('base','inTheta3',out(1,3));
            evalin("base",'FK_control');
        end

        % Button pushed function: StartCameraButton
        function StartCameraButtonPushed(comp, event)
            comp.cam = webcam(1);
            frame = snapshot(comp.cam);
            im = image(comp.UIAxes,zeros(size(frame),'uint8'));
            axis(comp.UIAxes,'image');
            
            preview(comp.cam,im);
            evalin("base",'setup_cam');
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(comp, event)
            evalin("base",'detect_pos');
            comp.XEditField.Value=evalin("base",'position(1,1)');
            comp.YEditField.Value=evalin("base",'position(1,2)');
            comp.ZEditField.Value=0;
            comp.EditField_2.Value = evalin("base",'color');
        end

        % Button pushed function: SelectCOMportButton
        function SelectCOMportButtonPushed(comp, event)
            c=comp.COMEditField.Value
            c=int2str(c);
            str=append("COM",c);
            a1=serialport(str,9600);
            assignin('base','a1',a1);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(comp, event)
            evalin('base','Reset_button');
        end

        % Value changed function: MagnetButton
        function MagnetButtonValueChanged(comp, event)
            value = comp.MagnetButton.Value;
            if value==0
                nc=0;
            end
            if value==1
                nc=1;
            end
            assignin('base','inNC',nc); 
            evalin('base','Magnet');
        end

        % Button pushed function: SelectCOMportButton_2
        function SelectCOMportButton_2Pushed(comp, event)
            c2=comp.COMEditField_2.Value
            c2=int2str(c2);
            str=append("COM",c2);
            a2=serialport(str,9600);
            assignin('base','a2',a2);
        end

        % Value changed function: COMEditField
        function COMEditFieldValueChanged(comp, event)
            value = comp.COMEditField.Value;
            
        end

        % Value changed function: COMEditField_2
        function COMEditField_2ValueChanged(comp, event)
            value = comp.COMEditField_2.Value;
            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 585 417];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.UIAxes = uiaxes(comp);
            comp.ForwardButton = uibutton(comp, 'push');
            comp.ForwardButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ForwardButtonPushed2, true);
            comp.ForwardButton.Text = 'Forward';
            comp.Joint1EditFieldLabel = uilabel(comp);
            comp.Joint1EditFieldLabel.Text = 'Joint 1';
            comp.Joint1EditField = uieditfield(comp, 'numeric');
            comp.Joint1EditField.Tag = 'Theta1';
            comp.Joint2EditFieldLabel = uilabel(comp);
            comp.Joint2EditFieldLabel.Text = 'Joint 2';
            comp.Joint2EditField = uieditfield(comp, 'numeric');
            comp.Joint2EditField.Tag = 'Theta2';
            comp.ZaxisEditFieldLabel = uilabel(comp);
            comp.ZaxisEditFieldLabel.Text = 'Z-axis';
            comp.ZaxisEditField = uieditfield(comp, 'numeric');
            comp.ZaxisEditField.Limits = [0 Inf];
            comp.ZaxisEditField.Tag = 'Theta3';
            comp.JointPositionLabel = uilabel(comp);
            comp.JointPositionLabel.Text = 'Joint Position';
            comp.InverseButton_2 = uibutton(comp, 'push');
            comp.InverseButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @InverseButton_2Pushed, true);
            comp.InverseButton_2.Text = 'Inverse';
            comp.WorkPositionLabel = uilabel(comp);
            comp.WorkPositionLabel.Text = 'Work Position';
            comp.XEditFieldLabel = uilabel(comp);
            comp.XEditFieldLabel.Text = 'X';
            comp.XEditField = uieditfield(comp, 'numeric');
            comp.XEditField.Tag = 'X';
            comp.XEditField.Value = 479;
            comp.YEditFieldLabel = uilabel(comp);
            comp.YEditFieldLabel.Text = 'Y';
            comp.YEditField = uieditfield(comp, 'numeric');
            comp.YEditField.Tag = 'Y';
            comp.ZEditFieldLabel = uilabel(comp);
            comp.ZEditFieldLabel.Text = 'Z';
            comp.ZEditField = uieditfield(comp, 'numeric');
            comp.ZEditField.Limits = [0 Inf];
            comp.ZEditField.Tag = 'Z';
            comp.StartCameraButton = uibutton(comp, 'push');
            comp.StartCameraButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @StartCameraButtonPushed, true);
            comp.StartCameraButton.Tag = 'Start_camera';
            comp.StartCameraButton.Text = 'Start Camera';
            comp.CameraViewLabel = uilabel(comp);
            comp.CameraViewLabel.Text = 'Camera View';
            comp.DetectButton = uibutton(comp, 'push');
            comp.DetectButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @DetectButtonPushed, true);
            comp.DetectButton.Tag = 'Snapshot';
            comp.DetectButton.Text = 'Detect';
            comp.EditField_2 = uieditfield(comp, 'text');
            comp.DetectedColorLabel = uilabel(comp);
            comp.DetectedColorLabel.Text = 'Detected Color';
            comp.SelectCOMportButton = uibutton(comp, 'push');
            comp.SelectCOMportButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SelectCOMportButtonPushed, true);
            comp.SelectCOMportButton.Text = 'Select COM port';
            comp.COMEditFieldLabel = uilabel(comp);
            comp.COMEditFieldLabel.Text = 'COM';
            comp.COMEditField = uieditfield(comp, 'numeric');
            comp.COMEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @COMEditFieldValueChanged, true);
            comp.ResetButton = uibutton(comp, 'push');
            comp.ResetButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ResetButtonPushed, true);
            comp.ResetButton.Text = 'Reset';
            comp.MagnetButton = uibutton(comp, 'state');
            comp.MagnetButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @MagnetButtonValueChanged, true);
            comp.MagnetButton.Text = 'Magnet';
            comp.SelectCOMportButton_2 = uibutton(comp, 'push');
            comp.SelectCOMportButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SelectCOMportButton_2Pushed, true);
            comp.SelectCOMportButton_2.Text = 'Select COM port';
            comp.COMEditFieldLabel_2 = uilabel(comp);
            comp.COMEditFieldLabel_2.Text = 'COM';
            comp.COMEditField_2 = uieditfield(comp, 'numeric');
            comp.COMEditField_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @COMEditField_2ValueChanged, true);
            comp.Controller1Label = uilabel(comp);
            comp.Controller1Label.Text = 'Controller 1';
            comp.Controller2Label = uilabel(comp);
            comp.Controller2Label.Text = 'Controller 2';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end